classdef ChangeDetectionPair < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InputImagesPanel          matlab.ui.container.Panel
        RefImage                  matlab.ui.control.Image
        LoadReferenceImageButton  matlab.ui.control.Button
        RefLamp                   matlab.ui.control.Lamp
        TestImage                 matlab.ui.control.Image
        LoadTestImageButton       matlab.ui.control.Button
        TestLamp                  matlab.ui.control.Lamp
        OutputImagesPanel         matlab.ui.container.Panel
        DifImage                  matlab.ui.control.Image
        TopnLblImage              matlab.ui.control.Image
        ComputeButton             matlab.ui.control.Button
        StatusTextAreaLabel       matlab.ui.control.Label
        StatusTextArea            matlab.ui.control.TextArea
        VisualiseButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        img_ref
        img_test
        img_dif
        maxClusters
        pointVec
        ImageViewerApp
        VisualiserApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            [refImagePath,user_canceled] = imgetfile('InitialPath','./');
            if(user_canceled)
                app.StatusTextArea.Value="Image input cancelled";
                return;
            end
            app.img_ref = imread(refImagePath);
            app.RefImage.ImageSource = refImagePath;
            app.StatusTextArea.Value= "Reference image loaded";
            app.RefLamp.Enable = true;
        end

        % Button pushed function: LoadTestImageButton
        function LoadTestImageButtonPushed(app, event)
            [testImagePath,user_canceled] = imgetfile('InitialPath','./');
            if(user_canceled)
                app.StatusTextArea.Value="Image input cancelled";
                return;
            end
            app.img_test = imread(testImagePath);
            app.TestImage.ImageSource = testImagePath;
            app.StatusTextArea.Value="Test image loaded";
            app.TestLamp.Enable = true;
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.StatusTextArea.Value="Computing difference map";
            app.img_dif = compare(app.img_ref,app.img_test);
            app.DifImage.ImageSource = ind2rgb((uint8(rescale(app.img_dif,0,255))),gray(256));
            app.StatusTextArea.Value="Performing NFA clustering";
            th = 3.5;
            ccf = 0.01;
            app.img_dif = imresize(app.img_dif,[90 NaN]);
            [app.maxClusters,app.pointVec,debug] = NFAC_gl(app.img_dif,ccf,th);
            app.StatusTextArea.Value="NFA clustering done";
            
            img_topn = top_n_label(app.maxClusters,app.pointVec,size(app.img_dif),5);
            img_topn = imresize(img_topn,[800, NaN],'Method',"nearest");
            app.TopnLblImage.ImageSource = ind2rgb((uint8(rescale(img_topn,0,255))),jet(256));
            app.VisualiseButton.Enable = true;
            
        end

        % Image clicked function: DifImage, RefImage, TestImage, 
        % TopnLblImage
        function ImageClicked(app, event)
            src = event.Source.ImageSource;
            if isstring(src)  
                if src == ""
                    return;
                end
            end
            app.ImageViewerApp = ImageViewer(app,src);
            
        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
            app.VisualiserApp = Visualiser(app,app.maxClusters,app.pointVec,app.img_dif,app.img_test);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputImagesPanel = uipanel(app.UIFigure);
            app.InputImagesPanel.Title = 'Input Images';
            app.RefImage = uiimage(app.InputImagesPanel);
            app.RefImage.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.LoadReferenceImageButton = uibutton(app.InputImagesPanel, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.RefLamp = uilamp(app.InputImagesPanel);
            app.RefLamp.Enable = 'off';
            app.TestImage = uiimage(app.InputImagesPanel);
            app.TestImage.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.LoadTestImageButton = uibutton(app.InputImagesPanel, 'push');
            app.LoadTestImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestImageButtonPushed, true);
            app.LoadTestImageButton.Text = 'Load Test Image';
            app.TestLamp = uilamp(app.InputImagesPanel);
            app.TestLamp.Enable = 'off';
            app.OutputImagesPanel = uipanel(app.UIFigure);
            app.OutputImagesPanel.Title = 'Output Images';
            app.DifImage = uiimage(app.OutputImagesPanel);
            app.DifImage.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.TopnLblImage = uiimage(app.OutputImagesPanel);
            app.TopnLblImage.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.VisualiseButton = uibutton(app.UIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Enable = 'off';
            app.VisualiseButton.Text = 'Visualise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChangeDetectionPair

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end