classdef Visualiser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        Export        matlab.ui.control.Button
        nSlider       matlab.ui.control.Slider
        nSliderLabel  matlab.ui.control.Label
        RightPanel    matlab.ui.container.Panel
        GridLayout2   matlab.ui.container.GridLayout
        PCAxes        matlab.ui.control.UIAxes
        TopnOverlay   matlab.ui.control.UIAxes
        TopnLblAxes   matlab.ui.control.UIAxes
        difImgAxes    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        callingApp 
        maxClusters
        pointVec
        img_dif
        img_test
    end
    
    methods (Access = private)
        
        function results = updateFigs(app)
            clrmap = [0,0,0;1,0,0;0,1,0;0,0,1;1,1,0;1,0,1;0,1,1;1,1,0.5;0.5,1,1;1,0.5,1;0.5,0.5,0.5];
            ticks = 1:10;
            n = app.nSlider.Value;
            img_topn = top_n_label(app.maxClusters,app.pointVec,size(app.img_dif),n);
            %img_topn = imresize(img_topn,[800, NaN],'Method',"nearest");
            
            imshow(img_topn,[],"Parent",app.TopnLblAxes,"Colormap",clrmap(1:n+1,:));
            colorbar(app.TopnLblAxes,"eastoutside",'Ticks',ticks(1:n));
            
            top_n_overlay(app.img_test,app.maxClusters,app.pointVec,size(app.img_dif),n,app.TopnOverlay);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, maxClusters, pointVec, difImg, img_test)
            app.callingApp = caller;
            app.maxClusters = maxClusters;
            app.pointVec = pointVec;
            app.img_dif = difImg;
            app.img_test = img_test;
            imshow(app.img_dif,[],"Parent",app.difImgAxes);
            updateFigs(app);
            visualisePointCloud(app.pointVec,app.img_dif,app.PCAxes);
        end

        % Value changed function: nSlider
        function nSliderValueChanged(app, event)
            updateFigs(app);
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            dname = uigetdir('.\','Select export directory');
            exportgraphics(app.difImgAxes,[dname,'/differenceImage.png']);
            exportgraphics(app.TopnLblAxes,[dname,'/top',num2str(app.nSlider.Value),'lableImage.png']);
            exportgraphics(app.TopnOverlay,[dname,'/top',num2str(app.nSlider.Value),'OverlayImage.png']);
            exportgraphics(app.PCAxes,[dname,'/3dpointcloud.png']);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {846, 846};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.nSliderLabel = uilabel(app.LeftPanel);
            app.nSliderLabel.Text = 'n';
            app.nSlider = uislider(app.LeftPanel);
            app.nSlider.Limits = [1 10];
            app.nSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.nSlider.Value = 4;
            app.Export = uibutton(app.LeftPanel, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = 'Export';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.difImgAxes = uiaxes(app.GridLayout2);
            title(app.difImgAxes, 'Difference Image')
            app.difImgAxes.XTick = [];
            app.difImgAxes.YTick = [];
            app.TopnLblAxes = uiaxes(app.GridLayout2);
            title(app.TopnLblAxes, 'Top n clusters')
            app.TopnOverlay = uiaxes(app.GridLayout2);
            title(app.TopnOverlay, 'Clusters on the test image')
            app.PCAxes = uiaxes(app.GridLayout2);
            title(app.PCAxes, '3D point cloud')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualiser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end